classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        FiltertypeDropDownLabel   matlab.ui.control.Label
        FiltertypeDropDown        matlab.ui.control.DropDown
        NSliderLabel              matlab.ui.control.Label
        NSlider                   matlab.ui.control.Slider
        LPFfrequencySliderLabel   matlab.ui.control.Label
        LPFfrequencySlider        matlab.ui.control.Slider
        ProcessfinishedLampLabel  matlab.ui.control.Label
        ProcessfinishedLamp       matlab.ui.control.Lamp
        FileloadedLampLabel       matlab.ui.control.Label
        FileloadedLamp            matlab.ui.control.Lamp
        CenterPanel               matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        Original_signal           matlab.ui.control.UIAxes
        Fre_Original              matlab.ui.control.UIAxes
        Processed_signal          matlab.ui.control.UIAxes
        Fre_Processed             matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        processButton             matlab.ui.control.Button
        playoriginalButton        matlab.ui.control.Button
        playprocessedButton       matlab.ui.control.Button
        LoadfileButton            matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        
        sample
        Freq_sample
        N
        LPF_frequency = 20;
        % add noise
        add_noise = 0
        % The frequency range
        Freq_low = 200;
        Freq_high = 7000;
        % type of filter
        filter_type = 'butter';
        processed % Description
        Property10 % Description
    end
    
    methods (Access = private)
        
        function output = speech_systhesis(app)
            audio = app.sample;
            
            % Process the signal
            output = tone_vocoder(app, audio);


        end
        
        function Freq_cochlea = cochlea(app)
            % divide the band
            d1 = log10((app.Freq_low/165.4)+1) / 0.06;
            d2 = log10((app.Freq_high/165.4)+1) / 0.06;
            d = linspace(d1, d2, app.N+1);
            Freq_cochlea = 165.4*(10.^(0.06*d)-1);
        end
        
        function output = LPF(app, input, cutoff)
            % Switch differnet filters
            if app.filter_type == 'butter'
                [b1, a1] = butter(4, cutoff/(app.Freq_sample/2), 'low');
                output = filter(b1, a1, input);
            end
        end
        
        function output = BPF(app, input, pass_band)
            if app.filter_type == 'butter'
                [b1, a1] = butter(2, pass_band/(app.Freq_sample/2));
        
                % Do band-pass filtering at band i
                sample_band = filter(b1, a1, input);
                
            end
            output = abs(sample_band);
            
        end
   
        function yn = tone_vocoder(app, input)
             % divide the frequency
            Freq_cochlea = cochlea(app);
            yn = 0:length(input)-1;
            yn = yn * 0;
        
            for band_index = 1:app.N
                band = [Freq_cochlea(band_index) Freq_cochlea(band_index+1)];
        
                % Design band-pass filter at band i and do band-pass filtering at band i
                sample_band = BPF(app, input, band);
                sample_band = abs(sample_band);
                        
                % Do full-wave rectification
                % apply low-pass filtering to get the envelope at band i
                filtered_sample_band = LPF(app, sample_band, app.LPF_frequency)
                
                % Generate a sinewave, whose frequency equals to the center frequency of the i-th bandpass filter
                fm = (band(1)+band(2))/2;
                xn = 0:length(app.sample)-1;
                sin_wave = sin(2*pi*fm*xn/app.Freq_sample);
                
                % Multiply the envelope signal and sinewave
                product = sin_wave .* filtered_sample_band;
                product = product / norm(product)*norm(app.sample);
                yn = yn + product;
               
            end

            yn = yn/norm(yn)*norm(app.sample);
            yn = yn.';
            
            
        end
        
        function output = noise_adder(app, input)
            % add noise
            % generate noise
            x = input;
            fs = app.Freq_sample
            noise=1-2*rand(1,app.N);
            sig=repmat(x,1,10);
            [Pxx,w0]=pwelch(sig,[],[],512,fs);
            b=fir2(3000,w0/(fs/2),sqrt(Pxx/max(Pxx)));
            SSN=filter(b,1,noise);
            SSN=SSN/norm(SSN)*norm(x)*10^(5/20);
%             SNR=20*log10(norm(x)/norm(SSN));
            sum=x+SSN';
            output=sum/norm(sum)*norm(x);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: processButton
        function processButtonPushed(app, event)
            % Load parameters
            app.ProcessfinishedLamp.Color = 'red';
            app.N = app.NSlider.Value;
            app.LPF_frequency = app.LPFfrequencySlider.Value;
            app.filter_type = app.FiltertypeDropDown.Value;
            
            % processing the signal
            app.processed = speech_systhesis(app);
            plot(app.Processed_signal, app.processed);
            plot(app.Fre_Processed,linspace(-app.Freq_sample/2, app.Freq_sample/2, length(app.sample)), abs(fftshift(fft(app.sample))));
            app.ProcessfinishedLamp.Color = 'green'
           
           
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            app.FileloadedLamp.Color = 'red'
            [app.sample, app.Freq_sample] = audioread('C_01_01.wav');
            app.sample = app.sample.';
            plot(app.Original_signal, app.sample);
            plot(app.Fre_Original,linspace(-app.Freq_sample/2, app.Freq_sample/2, length(app.sample)) ,abs(fftshift(fft(app.sample))));
            app.FileloadedLamp.Color = 'green'
        end

        % Button pushed function: playoriginalButton
        function playoriginalButtonPushed(app, event)
            sound(app.sample);
        end

        % Button pushed function: playprocessedButton
        function playprocessedButtonPushed(app, event)
            sound(app.processed);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {806, 806, 806};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {806, 806};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {325, '1x', 114};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Parameters';
            app.FiltertypeDropDownLabel = uilabel(app.LeftPanel);
            app.FiltertypeDropDownLabel.Text = 'Filter type';
            app.FiltertypeDropDown = uidropdown(app.LeftPanel);
            app.FiltertypeDropDown.Items = {'butter', 'Option 2', 'Option 3', 'Option 4'};
            app.FiltertypeDropDown.Value = 'butter';
            app.NSliderLabel = uilabel(app.LeftPanel);
            app.NSliderLabel.Text = 'N';
            app.NSlider = uislider(app.LeftPanel);
            app.NSlider.Limits = [4 50];
            app.NSlider.Value = 4;
            app.LPFfrequencySliderLabel = uilabel(app.LeftPanel);
            app.LPFfrequencySliderLabel.Text = 'LPF frequency';
            app.LPFfrequencySlider = uislider(app.LeftPanel);
            app.LPFfrequencySlider.Limits = [20 200];
            app.LPFfrequencySlider.Value = 20;
            app.ProcessfinishedLampLabel = uilabel(app.LeftPanel);
            app.ProcessfinishedLampLabel.Text = 'Process finished';
            app.ProcessfinishedLamp = uilamp(app.LeftPanel);
            app.FileloadedLampLabel = uilabel(app.LeftPanel);
            app.FileloadedLampLabel.Text = 'File loaded';
            app.FileloadedLamp = uilamp(app.LeftPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Title = 'Plots';
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.Original_signal = uiaxes(app.GridLayout2);
            title(app.Original_signal, 'Original Signal')
            xlabel(app.Original_signal, 't')
            ylabel(app.Original_signal, 'Amp')
            app.Fre_Original = uiaxes(app.GridLayout2);
            title(app.Fre_Original, 'Specturm of original signal')
            xlabel(app.Fre_Original, 'Frequency')
            ylabel(app.Fre_Original, 'Amp')
            app.Processed_signal = uiaxes(app.GridLayout2);
            title(app.Processed_signal, 'Processed signal')
            xlabel(app.Processed_signal, 't')
            ylabel(app.Processed_signal, 'Amp')
            app.Fre_Processed = uiaxes(app.GridLayout2);
            title(app.Fre_Processed, 'Specturm of processed signal')
            xlabel(app.Fre_Processed, 'Frequency')
            ylabel(app.Fre_Processed, 'Amp')
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Control';
            app.processButton = uibutton(app.RightPanel, 'push');
            app.processButton.ButtonPushedFcn = createCallbackFcn(app, @processButtonPushed, true);
            app.processButton.Text = 'process';
            app.playoriginalButton = uibutton(app.RightPanel, 'push');
            app.playoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @playoriginalButtonPushed, true);
            app.playoriginalButton.Text = 'play original';
            app.playprocessedButton = uibutton(app.RightPanel, 'push');
            app.playprocessedButton.ButtonPushedFcn = createCallbackFcn(app, @playprocessedButtonPushed, true);
            app.playprocessedButton.Text = 'play processed';
            app.LoadfileButton = uibutton(app.RightPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end